classdef MotionProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionUI                        matlab.ui.Figure
        OuterGrid                       matlab.ui.container.GridLayout
        MarkersDataButton               matlab.ui.control.Button
        GRFDataButton                   matlab.ui.control.Button
        EMGDataButton                   matlab.ui.control.Button
        CalorimetryDataButton           matlab.ui.control.Button
        MarkersDataPath                 matlab.ui.control.EditField
        GRFDataPath                     matlab.ui.control.EditField
        EMGDataPath                     matlab.ui.control.EditField
        CalorimetryDataPath             matlab.ui.control.EditField
        SubsetButton                    matlab.ui.control.Button
        SubsetSelectedLabel             matlab.ui.control.Label
        SubsetIndicator                 matlab.ui.control.Lamp
        FooterToolbox                   matlab.ui.control.Label
        FooterAuthor                    matlab.ui.control.Label
        FeedbackPanel                   matlab.ui.control.TextArea
        DetailedErrorInfoLabel          matlab.ui.control.Label
        ErrorSwitch                     matlab.ui.control.Switch
        SaveDirectoryButton             matlab.ui.control.Button
        SaveDirectoryPath               matlab.ui.control.EditField
        MarkersDataClearButton          matlab.ui.control.Button
        GRFDataClearButton              matlab.ui.control.Button
        EMGDataClearButton              matlab.ui.control.Button
        CalorimetryDataClearButton      matlab.ui.control.Button
        SaveDirectoryClearButton        matlab.ui.control.Button
        ProcessButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        HighLevelProcessingTab          matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        SystemOrientationPanel          matlab.ui.container.Panel
        CoordinateRotationGrid          matlab.ui.container.GridLayout
        ForwardLabel                    matlab.ui.control.Label
        UpLabel                         matlab.ui.control.Label
        RightLabel                      matlab.ui.control.Label
        GRFForwardDropDown              matlab.ui.control.DropDown
        GRFUpDropDown                   matlab.ui.control.DropDown
        GRFRightDropDown                matlab.ui.control.DropDown
        MarkersForwardDropDown          matlab.ui.control.DropDown
        MarkersUpDropDown               matlab.ui.control.DropDown
        MarkersRightDropDown            matlab.ui.control.DropDown
        GRFLabel                        matlab.ui.control.Label
        MarkersLabel                    matlab.ui.control.Label
        MotionSynchronisationPanel      matlab.ui.container.Panel
        MotionSynchronisationGrid       matlab.ui.container.GridLayout
        BaselineLabel                   matlab.ui.control.Label
        TimeDelaysLabel                 matlab.ui.control.Label
        MarkersCheckBox                 matlab.ui.control.CheckBox
        GRFCheckBox                     matlab.ui.control.CheckBox
        EMGCheckBox                     matlab.ui.control.CheckBox
        CalorimetryCheckBox             matlab.ui.control.CheckBox
        MarkersDelay                    matlab.ui.control.NumericEditField
        GRFDelay                        matlab.ui.control.NumericEditField
        EMGDelay                        matlab.ui.control.NumericEditField
        CalorimetryDelay                matlab.ui.control.NumericEditField
        DataProcessingOptionsPanel      matlab.ui.container.Panel
        DataProcessingOptionsGrid       matlab.ui.container.GridLayout
        MotionSynchronisationLabel      matlab.ui.control.Label
        MotionSynchronisationSwitch     matlab.ui.control.Switch
        SpeedCompensationSwitchLabel    matlab.ui.control.Label
        SpeedCompensationSwitch         matlab.ui.control.Switch
        GaitSegmentationLabel           matlab.ui.control.Label
        GaitSegmentationSwitch          matlab.ui.control.Switch
        CoordinateTransformationSwitchLabel  matlab.ui.control.Label
        CoordinateTransformationSwitch  matlab.ui.control.Switch
        GaitSegmentationPanel           matlab.ui.container.Panel
        GaitSegmentationGrid            matlab.ui.container.GridLayout
        ModePanel                       matlab.ui.container.ButtonGroup
        StanceButton                    matlab.ui.control.RadioButton
        KinematicsButton                matlab.ui.control.RadioButton
        FeetPanel                       matlab.ui.container.ButtonGroup
        LeftButton                      matlab.ui.control.RadioButton
        RightButton                     matlab.ui.control.RadioButton
        BothButton                      matlab.ui.control.RadioButton
        SpeedCompensationPanel          matlab.ui.container.Panel
        MotionCompensationGrid          matlab.ui.container.GridLayout
        SpeedDataPath                   matlab.ui.control.EditField
        CompensationModeButton          matlab.ui.control.Button
        ManualLabel                     matlab.ui.control.Label
        SpeedmsEditFieldLabel           matlab.ui.control.Label
        SpeedInput                      matlab.ui.control.NumericEditField
        CoordinateTransformationPanel   matlab.ui.container.Panel
        CoordinateTranslationGrid       matlab.ui.container.GridLayout
        TranslationmLabel               matlab.ui.control.Label
        XEditFieldLabel                 matlab.ui.control.Label
        XOffset                         matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        YOffset                         matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ZOffset                         matlab.ui.control.NumericEditField
        RotationdegLabel                matlab.ui.control.Label
        XEditFieldLabel_2               matlab.ui.control.Label
        XRotation                       matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        YRotation                       matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        ZRotation                       matlab.ui.control.NumericEditField
        LowLevelProcessingTab           matlab.ui.container.Tab
    end

    properties (Access = private)
       
        FileSelector % Separate helper app for selecting subset of files
        FileSet
        FileSubset
        NInputs = 0
        BaselineSelected = false
        
    end
    
    methods (Access = private)
        
        function value = getFirstDataPath(app)
            
            options = {'MarkersDataPath', 'GRFDataPath', 'EMGDataPath', ...
                'CalorimetryDataPath'};
            
            value = '';
            for i = 1:length(options)
                if ~isempty(app.(options{i}).Value)
                    value = app.(options{i}).Value;
                    break;
                end
            end
            
        end
        
        function updateFeedbackPanel(app, str, err)
            
            app.FeedbackPanel.Value = str;
            if err
                beep;
            end
            
        end
        
        function adjustMarkerRotationOptions(app, value)
            app.MarkersForwardDropDown.Enable = value;
            app.MarkersUpDropDown.Enable = value;
            app.MarkersRightDropDown.Enable = value;
        end
        
        function adjustGRFRotationOptions(app, value)
            app.GRFForwardDropDown.Enable = value;
            app.GRFUpDropDown.Enable = value;
            app.GRFRightDropDown.Enable = value;
        end
        
        function adjustMotionCompensationOptions(app, value)
            
            app.CompensationModeButton.Enable = value;
            
            if strcmp(value, 'Off')
                app.SpeedDataPath.Enable = value;
                app.SpeedInput.Enable = value;
            else
                switch app.CompensationModeButton.Text
                    case 'Unload'
                        app.SpeedDataPath.Enable = 'On';
                    case 'Load'
                        app.SpeedInput.Enable = 'On';
                end
            end
            
        end
        
        function adjustMotionSynchronisationOptions(app, value, array)
            
            
            if array(1)
                app.MarkersCheckBox.Enable = value;
                app.MarkersDelay.Enable = value;
            end
            if array(2)
                app.GRFCheckBox.Enable = value;
                app.GRFDelay.Enable = value;
            end
            if array(3)
                app.EMGCheckBox.Enable = value;
                app.EMGDelay.Enable = value;
            end
            if array(4)
                app.CalorimetryCheckBox.Enable = value;
                app.CalorimetryDelay.Enable = value;
            end
            
            app.adjustBaselineChecks([0, 0, 0, 0])
            
        end
        
        function adjustGaitSegmentationOptions(app, value)
            
            if strcmp(value, 'On')
                if ~isempty(app.MarkersDataPath.Value)
                    app.KinematicsButton.Enable = value;
                else
                    app.StanceButton.Value = 1;
                end
                if ~isempty(app.GRFDataPath.Value)
                    app.StanceButton.Enable = value;
                else
                    app.KinematicsButton.Value = 1;
                end
            else
                app.KinematicsButton.Enable = value;
                app.StanceButton.Enable = value;
            end
            app.LeftButton.Enable = value;
            app.RightButton.Enable = value;
            app.BothButton.Enable = value;
            
        end
        
        function adjustManualTranslationOptions(app, value)
            app.XOffset.Enable = value;
            app.YOffset.Enable = value;
            app.ZOffset.Enable = value;
        end
        
        function adjustCoordinateCalibrationOptions(app, value)
            app.LoadCalibrationDataButton.Enable = value;
            app.ManualInputButton.Enable = value;
            
            switch value
                case 'Off'
                    manual_value = 'Off';
                case 'On'
                    manual_value = app.ManualInputButton.Value;
            end
            
            app.adjustManualTranslationOptions(manual_value);
        end
        
        function states = getDataProcessingOptionStates(app)
            states{1} = app.CoordinateTransformationSwitch.Value;
            states{2} = app.MotionSynchronisationSwitch.Value;
            states{3} = app.SpeedCompensationSwitch.Value;
            states{4} = app.GaitSegmentationSwitch.Value;
            
        end
        
        function changeDataProcessingOptions(app, states, value)
            
            % Simulate turning switches off and on again
            if strcmp(states{1}, 'On')
                app.CoordinateTransformationSwitch.Value = value;
                app.coordinateTranslationSwitchPressed();
            end
            if strcmp(states{2}, 'On')
                app.MotionSynchronisationSwitch.Value = value;
                app.motionSynchronisationSwitchPressed();
            end
            if strcmp(states{3}, 'On')
                app.SpeedCompensationSwitch.Value = value;
                app.motionCompensationSwitchPressed();
            end
            if strcmp(states{4}, 'On')
                app.GaitSegmentationSwitch.Value = value;
                app.gaitSegmentationSwitchPressed();
            end
            
        end
        
        function resetDataProcessingOptions(app)
            
            states = app.getDataProcessingOptionStates();
            app.changeDataProcessingOptions(states, 'Off');
            app.changeDataProcessingOptions(states, 'On');
            
        end
        
        function adjustBaselineChecks(app, values)
            
            app.MarkersCheckBox.Value = values(1);
            app.GRFCheckBox.Value = values(2);
            app.EMGCheckBox.Value = values(3);
            app.CalorimetryCheckBox.Value = values(4);
            
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CoordinateTransformationSwitch
        function coordinateTranslationSwitchPressed(app, event)
            value = app.CoordinateTransformationSwitch.Value;
            
            if ~isempty(app.MarkersDataPath.Value)
                app.adjustCoordinateCalibrationOptions(value);
            else
                app.adjustCoordinateCalibrationOptions('Off');
                app.CoordinateTransformationSwitch.Value = 'Off';
                str = 'Require marker data for coordinate translation.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Button pushed function: MarkersDataButton
        function getMarkersDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set marker data path
                app.MarkersDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing marker data.');
                
                % Enable clear option
                app.MarkersDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
                
                % Enable the marker orientation options
                app.adjustMarkerRotationOptions('On');
            catch
            end
        end

        % Button pushed function: MarkersDataClearButton
        function clearMarkersDataPath(app, event)
            % Clear marker data path
            app.MarkersDataPath.Value = '';
            app.MarkersDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
            % Disable marker orientation options
            app.adjustMarkerRotationOptions('Off');
        end

        % Button pushed function: GRFDataButton
        function getGRFDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.GRFDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing GRF data.');
                
                % Enable clear option
                app.GRFDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
                
                % Enable the GRF orientation options
                app.adjustGRFRotationOptions('On');
            catch
            end
        end

        % Button pushed function: EMGDataButton
        function getEMGDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.EMGDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing EMG data.');
                
                % Enable clear option
                app.EMGDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: CalorimetryDataButton
        function getCalorimetryDataPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set data path
                app.CalorimetryDataPath.Value = ...
                    uigetdir(pwd, 'Select folder containing calorimetry data.');
                
                % Enable clear option
                app.CalorimetryDataClearButton.Enable = 'On';
                
                % Reset data processing settings
                app.resetDataProcessingOptions();
                
                % Add to the input count.
                app.NInputs = app.NInputs + 1;
            catch
            end
        end

        % Button pushed function: SaveDirectoryButton
        function getSaveDirectoryPath(app, event)
            % Use try-catch so user can cancel input without error
            try 
                % Get and set save directory
                app.SaveDirectoryPath.Value = ...
                    uigetdir(pwd, 'Select or create results folder.');
                
                % Enable clear option
                app.SaveDirectoryClearButton.Enable = 'On';
            catch
            end
        end

        % Value changed function: MotionSynchronisationSwitch
        function motionSynchronisationSwitchPressed(app, event)
            value = app.MotionSynchronisationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustMotionSynchronisationOptions(value, [1 1 1 1]);
                app.BaselineSelected = false;
            elseif strcmp(value, 'On')
                if app.NInputs() < 2
                    app.MotionSynchronisationSwitch.Value = 'Off';
                    str = ...
                        'Require 2 or more inputs for motion synchronisation.';
                    app.updateFeedbackPanel(str, 1);
                else
                    array = [false false false false];
                    if ~isempty(app.MarkersDataPath.Value)
                        array(1) = true;
                    end
                    if ~isempty(app.GRFDataPath.Value)
                        array(2) = true;
                    end
                    if ~isempty(app.EMGDataPath.Value)
                        array(3) = true;
                    end
                    if ~isempty(app.CalorimetryDataPath.Value)
                        array(4) = true;
                    end
                    app.adjustMotionSynchronisationOptions(value, array);
                end
            end
            
        end

        % Value changed function: SpeedCompensationSwitch
        function motionCompensationSwitchPressed(app, event)
            value = app.SpeedCompensationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustMotionCompensationOptions(value);
            elseif strcmp(value, 'On')
                if isempty(app.MarkersDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.SpeedCompensationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for motion compensation.';
                    app.updateFeedbackPanel(str, 1);
                else
                    app.adjustMotionCompensationOptions(value);
                end
            end
            
        end

        % Value changed function: GaitSegmentationSwitch
        function gaitSegmentationSwitchPressed(app, event)
            value = app.GaitSegmentationSwitch.Value;
            
            if strcmp(value, 'Off')
                app.adjustGaitSegmentationOptions(value);
            elseif strcmp(value, 'On')
                if isempty(app.MarkersDataPath.Value) && ...
                        isempty(app.GRFDataPath.Value)
                    app.GaitSegmentationSwitch.Value = 'Off';
                    str = 'Require marker or GRF data for gait segmentation.';
                    app.updateFeedbackPanel(str, 1);
                else
                    app.adjustGaitSegmentationOptions(value);
                end
            end
        end

        % Button pushed function: GRFDataClearButton
        function clearGRFDataPath(app, event)
            % Clear data path
            app.GRFDataPath.Value = '';
            app.GRFDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
            % Disable GRF orientation options
            app.adjustGRFRotationOptions('Off');
        end

        % Button pushed function: EMGDataClearButton
        function clearEMGDataPath(app, event)
            % Clear data path
            app.EMGDataPath.Value = '';
            app.EMGDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: CalorimetryDataClearButton
        function clearCalorimetryDataPath(app, event)
            % Clear data path
            app.CalorimetryDataPath.Value = '';
            app.CalorimetryDataClearButton.Enable = 'Off';
            
            % Subtract to the input count.
            app.NInputs = app.NInputs - 1;
            
            % Reset data processing settings
            app.resetDataProcessingOptions();
            
        end

        % Button pushed function: SaveDirectoryClearButton
        function clearSaveDirectoryPath(app, event)
            % Clear data path
            app.SaveDirectoryPath.Value = '';
            app.SaveDirectoryClearButton.Enable = 'Off';
        end

        % Value changed function: MarkersCheckBox
        function markersChecked(app, event)
            
            app.adjustBaselineChecks([1 0 0 0]);
            app.MarkersDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: GRFCheckBox
        function grfChecked(app, event)
            
            app.adjustBaselineChecks([0 1 0 0]);
            app.GRFDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: EMGCheckBox
        function emgChecked(app, event)
            
            app.adjustBaselineChecks([0 0 1 0]);
            app.EMGDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: CalorimetryCheckBox
        function calorimetryChecked(app, event)
            
            app.adjustBaselineChecks([0 0 0 1]);
            app.CalorimetryDelay.Value = 0;
            app.BaselineSelected = true;
            
        end

        % Value changed function: MarkersDelay
        function markerDelayModified(app, event)
            
            if app.MarkersCheckBox.Value
                app.MarkersDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Value changed function: GRFDelay
        function grfDelayModified(app, event)
            
            if app.GRFCheckBox.Value
                app.GRFDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Value changed function: EMGDelay
        function emgDelayModified(app, event)
            
            if app.EMGCheckBox.Value
                app.EMGDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Value changed function: CalorimetryDelay
        function calorimetryDelayModified(app, event)
            
            if app.CalorimetryCheckBox.Value
                app.CalorimetryDelay.Value = 0;
                str = 'Baseline must have 0 delay.';
                app.updateFeedbackPanel(str, 1);
            end
            
        end

        % Button pushed function: SubsetButton
        function chooseFileSubset(app, event)
            app.SubsetButton.Enable = 'Off';
            
            path = app.getFirstDataPath();
            
            if isempty(path)
                str = 'Require data to choose file subset.';
                app.updateFeedbackPanel(str, 1);
                app.FileSelectorButton.Enable = 'On';
            else
                [~, files] = dirNoDots(path);
            end
            app.FileSet = files;
            app.FileSelector = FileSelectorApp(app, files);
        end

        % Button pushed function: CompensationModeButton
        function changeCompensationMode(app, event)
            
             % Get button name
            name = app.CompensationModeButton.Text;
            
            % Different behaviour depending on 'pressing' vs 'unpressing'
            switch name
                case 'Load'
                    
                    % Use try-catch so user can cancel input without error
                    try
                        % Get and set marker data path
                        app.SpeedDataPath.Value = uigetdir(...
                            pwd, 'Select folder containing speed data.');
                    catch
                        return;
                    end
                    
                    % Change which components are enabled
                    enable_status = {'On', 'Off'};
                    
                    % Change button name
                    app.CompensationModeButton.Text = 'Unload';
     
                case 'Unload'
                    app.SpeedDataPath.Value = '';
                    
                    % Change which components are enabled
                    enable_status = {'Off', 'On'};
                    
                    % Change button name
                    app.CompensationModeButton.Text = 'Load';
            end
            
            % Change which components are enabled
            app.SpeedDataPath.Enable = enable_status{1};
            app.SpeedInput.Enable = enable_status{2};
            
        end

        % Button pushed function: ProcessButton
        function processData(app, event)
            
            % Require at least some data
            if app.NInputs < 1
                str = 'Require at least one data path for processing.';
                app.updateFeedbackPanel(str, 1);
                return;
            end
            
            % Require a designated save directory
            if isempty(app.SaveDirectoryPath.Value)
                str = 'Processing requires save directory.';
                app.updateFeedbackPanel(str, 1);
                return;
            end
            
            % Require user to choose baseline for motion synchronisation 
            if strcmp(app.MotionSynchronisationSwitch.Value, 'On') && ...
                    ~app.BaselineSelected
                str = 'Please select motion synchronisation baseline.';
                app.updateFeedbackPanel(str, 1);
                return;
            end
            
            % Create a struct to store data paths
            paths = struct();
            
            % Step through and store data paths 
            streams = {'Markers', 'GRF', 'EMG', 'Calorimetry'};
            for i = 1:length(streams)
                fieldname = [streams{i} 'DataPath'];
                app.(fieldname).Value
                if ~isempty(app.(fieldname).Value)
                    paths.(streams{i}) = app.(fieldname).Value;
                end
            end
            streams = fieldnames(paths);
            
            % Store results path
            paths.SaveDirectory = app.SaveDirectoryPath.Value;
            
            % Create a struct to store settings options
            settings = struct();
            
            % Step through storing processing settings
            
            % Motion Synchronisation
            if strcmp(app.MotionSynchronisationSwitch.Value, 'On')
                for i = 1:length(streams)
                    delay_str = [streams{i} 'Delay'];
                    baseline_str = [streams{i} 'CheckBox'];
                    settings.(delay_str) = app.(delay_str).Value;
                    if app.(baseline_str).Value 
                        settings.Baseline = streams{i};
                    end
                end
            end
            
            % System orientation
            systems = intersect(streams, {'Markers', 'GRF'});
            directions = {'Forward', 'Up', 'Right'};
            for i = 1:length(systems)
                for j = 1:length(directions)
                    settings.([systems{i} 'System']).(directions{j}) = ...
                        app.([systems{i} directions{j} 'DropDown']).Value;
                end
            end
            
            % Gait segmentation
            if strcmp(app.GaitSegmentationSwitch.Value, 'On')
                settings.SegmentationMode = app.ModePanel.SelectedObject.Text;
                settings.Feet = app.FeetPanel.SelectedObject.Text;
            end
            
            % Speed compensation
            if strcmp(app.SpeedCompensationSwitch.Value, 'On')
                if strcmp(app.CompensationModeButton.Text, 'Load')
                    settings.Speed = app.SpeedInput.Value;
                else
                    settings.Speed = app.SpeedDataPath.Value;
                end
            end
            
            % Coordinate transformation
            if strcmp(app.CoordinateTransformationSwitch.Value, 'On')
                settings.CoordinateTransformation = ...
                    [app.XOffset.Value, app.YOffset.Value, ...
                    app.ZOffset.Value, app.XRotation.Value, ...
                    app.YRotation.Value, app.ZRotation.Value];
            end
            
            % Note detailed error status
            if strcmp(app.ErrorSwitch.Value, 'On')
                settings.info = true;
            else
                settings.info = false;
            end
            
            % Finally, pass paths & settings information to batch processor
            app.updateFeedbackPanel('Processing...', 0);
            err = batchProcessData(paths, settings);
            if ~err
                app.updateFeedbackPanel('Processing complete.', 0);
            else
                app.updateFeedbackPanel(['Processing finished with errors - '...
                    'see MATLAB command window for more details.', 1]);
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionUI = uifigure('Visible', 'off');
            app.MotionUI.Name = 'Motion Processor';
            app.OuterGrid = uigridlayout(app.MotionUI);
            app.MarkersDataButton = uibutton(app.OuterGrid, 'push');
            app.MarkersDataButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkersDataPath, true);
            app.MarkersDataButton.Text = 'Marker Data';
            app.GRFDataButton = uibutton(app.OuterGrid, 'push');
            app.GRFDataButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFDataPath, true);
            app.GRFDataButton.Text = 'GRF Data';
            app.EMGDataButton = uibutton(app.OuterGrid, 'push');
            app.EMGDataButton.ButtonPushedFcn = createCallbackFcn(app, @getEMGDataPath, true);
            app.EMGDataButton.Text = 'EMG Data';
            app.CalorimetryDataButton = uibutton(app.OuterGrid, 'push');
            app.CalorimetryDataButton.ButtonPushedFcn = createCallbackFcn(app, @getCalorimetryDataPath, true);
            app.CalorimetryDataButton.Text = 'Calorimetry Data';
            app.MarkersDataPath = uieditfield(app.OuterGrid, 'text');
            app.MarkersDataPath.Editable = 'off';
            app.GRFDataPath = uieditfield(app.OuterGrid, 'text');
            app.GRFDataPath.Editable = 'off';
            app.EMGDataPath = uieditfield(app.OuterGrid, 'text');
            app.EMGDataPath.Editable = 'off';
            app.CalorimetryDataPath = uieditfield(app.OuterGrid, 'text');
            app.CalorimetryDataPath.Editable = 'off';
            app.SubsetButton = uibutton(app.OuterGrid, 'push');
            app.SubsetButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileSubset, true);
            app.SubsetButton.Text = 'Choose Subset of Data Files';
            app.SubsetSelectedLabel = uilabel(app.OuterGrid);
            app.SubsetSelectedLabel.Text = 'Subset Selected?';
            app.SubsetIndicator = uilamp(app.OuterGrid);
            app.FooterToolbox = uilabel(app.OuterGrid);
            app.FooterToolbox.Text = 'Component of Gait Analysis Toolbox';
            app.FooterAuthor = uilabel(app.OuterGrid);
            app.FooterAuthor.Text = 'Daniel Gordon 2020';
            app.FeedbackPanel = uitextarea(app.OuterGrid);
            app.FeedbackPanel.Editable = 'off';
            app.DetailedErrorInfoLabel = uilabel(app.OuterGrid);
            app.DetailedErrorInfoLabel.Text = 'Detailed Error Info';
            app.ErrorSwitch = uiswitch(app.OuterGrid, 'slider');
            app.SaveDirectoryButton = uibutton(app.OuterGrid, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getSaveDirectoryPath, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectoryPath = uieditfield(app.OuterGrid, 'text');
            app.SaveDirectoryPath.Editable = 'off';
            app.MarkersDataClearButton = uibutton(app.OuterGrid, 'push');
            app.MarkersDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearMarkersDataPath, true);
            app.MarkersDataClearButton.Enable = 'off';
            app.MarkersDataClearButton.Text = 'Clear';
            app.GRFDataClearButton = uibutton(app.OuterGrid, 'push');
            app.GRFDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearGRFDataPath, true);
            app.GRFDataClearButton.Enable = 'off';
            app.GRFDataClearButton.Text = 'Clear';
            app.EMGDataClearButton = uibutton(app.OuterGrid, 'push');
            app.EMGDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearEMGDataPath, true);
            app.EMGDataClearButton.Enable = 'off';
            app.EMGDataClearButton.Text = 'Clear';
            app.CalorimetryDataClearButton = uibutton(app.OuterGrid, 'push');
            app.CalorimetryDataClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearCalorimetryDataPath, true);
            app.CalorimetryDataClearButton.Enable = 'off';
            app.CalorimetryDataClearButton.Text = 'Clear';
            app.SaveDirectoryClearButton = uibutton(app.OuterGrid, 'push');
            app.SaveDirectoryClearButton.ButtonPushedFcn = createCallbackFcn(app, @clearSaveDirectoryPath, true);
            app.SaveDirectoryClearButton.Enable = 'off';
            app.SaveDirectoryClearButton.Text = 'Clear';
            app.ProcessButton = uibutton(app.OuterGrid, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processData, true);
            app.ProcessButton.Text = 'Process';
            app.TabGroup = uitabgroup(app.OuterGrid);
            app.HighLevelProcessingTab = uitab(app.TabGroup);
            app.HighLevelProcessingTab.Title = 'High Level Processing';
            app.GridLayout = uigridlayout(app.HighLevelProcessingTab);
            app.SystemOrientationPanel = uipanel(app.GridLayout);
            app.SystemOrientationPanel.Title = 'System Orientation';
            app.CoordinateRotationGrid = uigridlayout(app.SystemOrientationPanel);
            app.ForwardLabel = uilabel(app.CoordinateRotationGrid);
            app.ForwardLabel.Text = 'Forward';
            app.UpLabel = uilabel(app.CoordinateRotationGrid);
            app.UpLabel.Text = 'Up';
            app.RightLabel = uilabel(app.CoordinateRotationGrid);
            app.RightLabel.Text = 'Right';
            app.GRFForwardDropDown = uidropdown(app.CoordinateRotationGrid);
            app.GRFForwardDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFForwardDropDown.Enable = 'off';
            app.GRFForwardDropDown.Value = '+x';
            app.GRFUpDropDown = uidropdown(app.CoordinateRotationGrid);
            app.GRFUpDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFUpDropDown.Enable = 'off';
            app.GRFUpDropDown.Value = '+y';
            app.GRFRightDropDown = uidropdown(app.CoordinateRotationGrid);
            app.GRFRightDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRFRightDropDown.Enable = 'off';
            app.GRFRightDropDown.Value = '+z';
            app.MarkersForwardDropDown = uidropdown(app.CoordinateRotationGrid);
            app.MarkersForwardDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.MarkersForwardDropDown.Enable = 'off';
            app.MarkersForwardDropDown.Value = '+x';
            app.MarkersUpDropDown = uidropdown(app.CoordinateRotationGrid);
            app.MarkersUpDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.MarkersUpDropDown.Enable = 'off';
            app.MarkersUpDropDown.Value = '+y';
            app.MarkersRightDropDown = uidropdown(app.CoordinateRotationGrid);
            app.MarkersRightDropDown.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.MarkersRightDropDown.Enable = 'off';
            app.MarkersRightDropDown.Value = '+z';
            app.GRFLabel = uilabel(app.CoordinateRotationGrid);
            app.GRFLabel.Text = 'GRF';
            app.MarkersLabel = uilabel(app.CoordinateRotationGrid);
            app.MarkersLabel.Text = 'Markers';
            app.MotionSynchronisationPanel = uipanel(app.GridLayout);
            app.MotionSynchronisationPanel.Title = 'Motion Synchronisation';
            app.MotionSynchronisationGrid = uigridlayout(app.MotionSynchronisationPanel);
            app.BaselineLabel = uilabel(app.MotionSynchronisationGrid);
            app.BaselineLabel.Text = 'Baseline';
            app.TimeDelaysLabel = uilabel(app.MotionSynchronisationGrid);
            app.TimeDelaysLabel.Text = 'Time Delay (s)';
            app.MarkersCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.MarkersCheckBox.ValueChangedFcn = createCallbackFcn(app, @markersChecked, true);
            app.MarkersCheckBox.Enable = 'off';
            app.MarkersCheckBox.Text = 'Markers';
            app.GRFCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.GRFCheckBox.ValueChangedFcn = createCallbackFcn(app, @grfChecked, true);
            app.GRFCheckBox.Enable = 'off';
            app.GRFCheckBox.Text = 'GRF';
            app.EMGCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.EMGCheckBox.ValueChangedFcn = createCallbackFcn(app, @emgChecked, true);
            app.EMGCheckBox.Enable = 'off';
            app.EMGCheckBox.Text = 'EMG';
            app.CalorimetryCheckBox = uicheckbox(app.MotionSynchronisationGrid);
            app.CalorimetryCheckBox.ValueChangedFcn = createCallbackFcn(app, @calorimetryChecked, true);
            app.CalorimetryCheckBox.Enable = 'off';
            app.CalorimetryCheckBox.Text = 'Calorimetry';
            app.MarkersDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.MarkersDelay.ValueDisplayFormat = '%.2f';
            app.MarkersDelay.ValueChangedFcn = createCallbackFcn(app, @markerDelayModified, true);
            app.MarkersDelay.Enable = 'off';
            app.GRFDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.GRFDelay.ValueDisplayFormat = '%.2f';
            app.GRFDelay.ValueChangedFcn = createCallbackFcn(app, @grfDelayModified, true);
            app.GRFDelay.Enable = 'off';
            app.EMGDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.EMGDelay.ValueDisplayFormat = '%.2f';
            app.EMGDelay.ValueChangedFcn = createCallbackFcn(app, @emgDelayModified, true);
            app.EMGDelay.Enable = 'off';
            app.CalorimetryDelay = uieditfield(app.MotionSynchronisationGrid, 'numeric');
            app.CalorimetryDelay.ValueDisplayFormat = '%.2f';
            app.CalorimetryDelay.ValueChangedFcn = createCallbackFcn(app, @calorimetryDelayModified, true);
            app.CalorimetryDelay.Enable = 'off';
            app.DataProcessingOptionsPanel = uipanel(app.GridLayout);
            app.DataProcessingOptionsPanel.Title = 'Data Processing Options';
            app.DataProcessingOptionsGrid = uigridlayout(app.DataProcessingOptionsPanel);
            app.MotionSynchronisationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.MotionSynchronisationLabel.Text = 'Motion Synchronisation';
            app.MotionSynchronisationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.MotionSynchronisationSwitch.ValueChangedFcn = createCallbackFcn(app, @motionSynchronisationSwitchPressed, true);
            app.SpeedCompensationSwitchLabel = uilabel(app.DataProcessingOptionsGrid);
            app.SpeedCompensationSwitchLabel.Text = 'Speed Compensation';
            app.SpeedCompensationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.SpeedCompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @motionCompensationSwitchPressed, true);
            app.GaitSegmentationLabel = uilabel(app.DataProcessingOptionsGrid);
            app.GaitSegmentationLabel.Text = 'Gait Segmentation';
            app.GaitSegmentationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.GaitSegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @gaitSegmentationSwitchPressed, true);
            app.CoordinateTransformationSwitchLabel = uilabel(app.DataProcessingOptionsGrid);
            app.CoordinateTransformationSwitchLabel.Text = 'Coordinate Transformation';
            app.CoordinateTransformationSwitch = uiswitch(app.DataProcessingOptionsGrid, 'slider');
            app.CoordinateTransformationSwitch.ValueChangedFcn = createCallbackFcn(app, @coordinateTranslationSwitchPressed, true);
            app.GaitSegmentationPanel = uipanel(app.GridLayout);
            app.GaitSegmentationPanel.Title = 'Gait Segmentation';
            app.GaitSegmentationGrid = uigridlayout(app.GaitSegmentationPanel);
            app.ModePanel = uibuttongroup(app.GaitSegmentationGrid);
            app.ModePanel.Title = 'Mode';
            app.StanceButton = uiradiobutton(app.ModePanel);
            app.StanceButton.Enable = 'off';
            app.StanceButton.Text = 'Stance';
            app.StanceButton.Value = true;
            app.KinematicsButton = uiradiobutton(app.ModePanel);
            app.KinematicsButton.Enable = 'off';
            app.KinematicsButton.Text = 'Kinematics';
            app.FeetPanel = uibuttongroup(app.GaitSegmentationGrid);
            app.FeetPanel.Title = 'Feet';
            app.LeftButton = uiradiobutton(app.FeetPanel);
            app.LeftButton.Enable = 'off';
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.FeetPanel);
            app.RightButton.Enable = 'off';
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.FeetPanel);
            app.BothButton.Enable = 'off';
            app.BothButton.Text = 'Both';
            app.SpeedCompensationPanel = uipanel(app.GridLayout);
            app.SpeedCompensationPanel.Title = 'Speed Compensation';
            app.MotionCompensationGrid = uigridlayout(app.SpeedCompensationPanel);
            app.SpeedDataPath = uieditfield(app.MotionCompensationGrid, 'text');
            app.SpeedDataPath.Editable = 'off';
            app.SpeedDataPath.Enable = 'off';
            app.CompensationModeButton = uibutton(app.MotionCompensationGrid, 'push');
            app.CompensationModeButton.ButtonPushedFcn = createCallbackFcn(app, @changeCompensationMode, true);
            app.CompensationModeButton.Enable = 'off';
            app.CompensationModeButton.Text = 'Load';
            app.ManualLabel = uilabel(app.MotionCompensationGrid);
            app.ManualLabel.Text = 'Manual';
            app.SpeedmsEditFieldLabel = uilabel(app.MotionCompensationGrid);
            app.SpeedmsEditFieldLabel.Text = 'Speed (m/s)';
            app.SpeedInput = uieditfield(app.MotionCompensationGrid, 'numeric');
            app.SpeedInput.ValueDisplayFormat = '%.2f';
            app.SpeedInput.Enable = 'off';
            app.CoordinateTransformationPanel = uipanel(app.GridLayout);
            app.CoordinateTransformationPanel.Title = 'Coordinate Transformation';
            app.CoordinateTranslationGrid = uigridlayout(app.CoordinateTransformationPanel);
            app.TranslationmLabel = uilabel(app.CoordinateTranslationGrid);
            app.TranslationmLabel.Text = 'Translation (m)';
            app.XEditFieldLabel = uilabel(app.CoordinateTranslationGrid);
            app.XEditFieldLabel.Enable = 'off';
            app.XEditFieldLabel.Text = 'X';
            app.XOffset = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.XOffset.ValueDisplayFormat = '%.2f';
            app.XOffset.Editable = 'off';
            app.XOffset.Enable = 'off';
            app.YEditFieldLabel = uilabel(app.CoordinateTranslationGrid);
            app.YEditFieldLabel.Enable = 'off';
            app.YEditFieldLabel.Text = 'Y';
            app.YOffset = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.YOffset.ValueDisplayFormat = '%.2f';
            app.YOffset.Editable = 'off';
            app.YOffset.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.CoordinateTranslationGrid);
            app.ZEditFieldLabel.Enable = 'off';
            app.ZEditFieldLabel.Text = 'Z';
            app.ZOffset = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.ZOffset.ValueDisplayFormat = '%.2f';
            app.ZOffset.Editable = 'off';
            app.ZOffset.Enable = 'off';
            app.RotationdegLabel = uilabel(app.CoordinateTranslationGrid);
            app.RotationdegLabel.Text = 'Rotation (deg)';
            app.XEditFieldLabel_2 = uilabel(app.CoordinateTranslationGrid);
            app.XEditFieldLabel_2.Enable = 'off';
            app.XEditFieldLabel_2.Text = 'X';
            app.XRotation = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.XRotation.ValueDisplayFormat = '%.2f';
            app.XRotation.Editable = 'off';
            app.XRotation.Enable = 'off';
            app.YEditFieldLabel_2 = uilabel(app.CoordinateTranslationGrid);
            app.YEditFieldLabel_2.Enable = 'off';
            app.YEditFieldLabel_2.Text = 'Y';
            app.YRotation = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.YRotation.ValueDisplayFormat = '%.2f';
            app.YRotation.Editable = 'off';
            app.YRotation.Enable = 'off';
            app.ZEditFieldLabel_2 = uilabel(app.CoordinateTranslationGrid);
            app.ZEditFieldLabel_2.Enable = 'off';
            app.ZEditFieldLabel_2.Text = 'Z';
            app.ZRotation = uieditfield(app.CoordinateTranslationGrid, 'numeric');
            app.ZRotation.ValueDisplayFormat = '%.2f';
            app.ZRotation.Editable = 'off';
            app.ZRotation.Enable = 'off';
            app.LowLevelProcessingTab = uitab(app.TabGroup);
            app.LowLevelProcessingTab.Title = 'Low Level Processing';
            % Show the figure after all components are created
            app.MotionUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotionProcessor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionUI)
        end
    end
end