classdef ProcessMotionData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MotionDataProcessingAppUIFigure  matlab.ui.Figure
        ChooseAnalysisModeDropDownLabel  matlab.ui.control.Label
        ChooseAnalysisModeDropDown     matlab.ui.control.DropDown
        MarkerDataFolderButton         matlab.ui.control.Button
        MarkerFolder                   matlab.ui.control.EditField
        GRFDataFolderButton            matlab.ui.control.Button
        GRFFolder                      matlab.ui.control.EditField
        SaveDirectoryButton            matlab.ui.control.Button
        SaveDirectory                  matlab.ui.control.EditField
        DisplayDetailedInfoCheckBox    matlab.ui.control.CheckBox
        ProcessButton                  matlab.ui.control.Button
        SegmentationSwitchLabel        matlab.ui.control.Label
        SegmentationSwitch             matlab.ui.control.Switch
        TextArea                       matlab.ui.control.TextArea
        ComponentofGaitAnalysisToolboxLabel  matlab.ui.control.Label
        DanielGordon2019Label          matlab.ui.control.Label
        DefaultSaveFoldersCheckBox     matlab.ui.control.CheckBox
        MotionCompensationSwitchLabel  matlab.ui.control.Label
        MotionCompensationSwitch       matlab.ui.control.Switch
        SpeedDataFolderButton          matlab.ui.control.Button
        SpeedFolder                    matlab.ui.control.EditField
        CoordinateTransformationPanel  matlab.ui.container.Panel
        ForwardLabel                   matlab.ui.control.Label
        UpLabel                        matlab.ui.control.Label
        RightLabel                     matlab.ui.control.Label
        MarkersLabel                   matlab.ui.control.Label
        GRFLabel                       matlab.ui.control.Label
        Markers_F                      matlab.ui.control.DropDown
        Markers_U                      matlab.ui.control.DropDown
        Markers_R                      matlab.ui.control.DropDown
        GRF_F                          matlab.ui.control.DropDown
        GRF_U                          matlab.ui.control.DropDown
        GRF_R                          matlab.ui.control.DropDown
        GaitSegmentationPanel          matlab.ui.container.Panel
        ModeButtonGroup                matlab.ui.container.ButtonGroup
        StanceButton                   matlab.ui.control.RadioButton
        HipAngleButton                 matlab.ui.control.RadioButton
        FeetButtonGroup                matlab.ui.container.ButtonGroup
        LeftButton                     matlab.ui.control.RadioButton
        RightButton                    matlab.ui.control.RadioButton
        BothButton                     matlab.ui.control.RadioButton
        CutoffNLabel                   matlab.ui.control.Label
        StanceCutoff                   matlab.ui.control.NumericEditField
        DataTimeDelaysLabel            matlab.ui.control.Label
        DataTimeDelay                  matlab.ui.control.NumericEditField
        MotionCompensationPanel        matlab.ui.container.Panel
        MotionSpeedmsLabel             matlab.ui.control.Label
        MotionSpeed                    matlab.ui.control.NumericEditField
        MotionTiltdegLabel             matlab.ui.control.Label
        MotionTilt                     matlab.ui.control.NumericEditField
        MarkersEditField               matlab.ui.control.EditField
        MarkersEditFieldLabel          matlab.ui.control.Label
        GRFEditField                   matlab.ui.control.EditField
        GRFEditFieldLabel              matlab.ui.control.Label
        FileSelectorButton             matlab.ui.control.Button
        SubsetSelectedLampLabel        matlab.ui.control.Label
        SubsetSelectedLamp             matlab.ui.control.Lamp
        CoordinateSystemOffsetsPanel   matlab.ui.container.Panel
        YEditFieldLabel                matlab.ui.control.Label
        YOffset                        matlab.ui.control.NumericEditField
        ZEditFieldLabel                matlab.ui.control.Label
        ZOffset                        matlab.ui.control.NumericEditField
        XEditFieldLabel                matlab.ui.control.Label
        XOffset                        matlab.ui.control.NumericEditField
        LoadCalibrationDataButton      matlab.ui.control.Button
        ManualOverrideSwitchLabel      matlab.ui.control.Label
        OffsetOverrideSwitch           matlab.ui.control.Switch
        ApplyParameterisedAssistancePanel  matlab.ui.container.Panel
        FallEditFieldLabel             matlab.ui.control.Label
        FallEditField                  matlab.ui.control.NumericEditField
        PeakLabel                      matlab.ui.control.Label
        PeakEditField                  matlab.ui.control.NumericEditField
        RiseLabel                      matlab.ui.control.Label
        RiseEditField                  matlab.ui.control.NumericEditField
        MagnitudeLabel                 matlab.ui.control.Label
        MagnitudeEditField             matlab.ui.control.NumericEditField
        AssistanceButton               matlab.ui.control.Switch
    end

    
    properties (Access = private)
        FileSelector % Separate helper app for selecting subset of files
        FileSet
        FileSubset
    end

    methods (Access = private)
    
        function setAssistanceOptions(app, value)
           
            set(app.MagnitudeEditField, 'Enable', value);
            set(app.RiseEditField, 'Enable', value);
            set(app.PeakEditField, 'Enable', value);
            set(app.FallEditField, 'Enable', value);
            
        end
        
        function setGRFOptions(app, status)
            
            set(app.GRFDataFolderButton, 'Enable', status);
            set(app.GRF_F, 'Enable', status);
            set(app.GRF_U, 'Enable', status);
            set(app.GRF_R, 'Enable', status);
            
        end
    
        function setSegmentationOptions(app, status)

            setStanceOptions(app, status);
            setToeOptions(app, status);
            set(app.StanceCutoff, 'Enable', status);
            set(app.LeftButton, 'Enable', status);
            set(app.RightButton, 'Enable', status);
            set(app.BothButton, 'Enable', status);
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Marker'
                    app.HipAngleButton.Value = 1;
                    setStanceOptions(app, 'Off');
                    setToeOptions(app, status);
                case 'GRF'
                    app.StanceButton.Value = 1;
                    setToeOptions(app, 'Off');
                    setStanceOptions(app, status);
                case 'Motion'
                    setToeOptions(app, status);
                    setStanceOptions(app, status);
            end
            
        end
    
        function setCompensationOptions(app, status)
            
            set(app.MotionSpeed, 'Enable', status);
            set(app.MotionTilt, 'Enable', status);
            set(app.SpeedDataFolderButton, 'Enable', status);
            
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Marker'
                    set(app.MotionTilt, 'Enable', 'Off');
            end
            
        end
    
        function setStanceOptions(app, status)
            
            set(app.StanceButton, 'Enable', status);
            set(app.StanceCutoff, 'Enable', status);
            
        end
    
        function setToeOptions(app, status)
            
            set(app.HipAngleButton, 'Enable', status);
        end
    
        function setMarkerOptions(app, status)
            
            set(app.MarkerDataFolderButton, 'Enable', status);
            set(app.Markers_F, 'Enable', status);
            set(app.Markers_U, 'Enable', status);
            set(app.Markers_R, 'Enable', status);
            
        end
    
        function setSynchronisationOptions(app, status)
           
            set(app.DataTimeDelay, 'Enable', status);
            
        end
    
        function base = getBestStartPath(app)
            
            possibilities = {app.MarkerFolder.Value, app.GRFFolder.Value, app.SaveDirectory.Value};
            base = [];
            for i=1:length(possibilities)
                if ~isempty(possibilities{i})
                    components = strsplit(possibilities{i}, filesep);
                    base = strjoin(components(1:end-1), filesep);
                    break;
                end
            end
            
        end
        
    end
    
    methods (Access = public)
        
        function updateFileSubset(app, file_subset)
            
            app.FileSubset = file_subset;
            
            if length(app.FileSubset) < length(app.FileSet)
                app.SubsetSelectedLamp.Color = [0.00,1.00,0.00];
            else
                app.SubsetSelectedLamp.Color = [1.00,0.00,0.00];
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableOptions(app)
            setSegmentationOptions(app, 'off')
        end

        % Button pushed function: MarkerDataFolderButton
        function getMarkerFolder(app, event)
            base = getBestStartPath(app);
            try
                app.MarkerFolder.Value = uigetdir(base, 'Select folder containing marker data');
            catch 
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Value changed function: ChooseAnalysisModeDropDown
        function disableOptions(app, event)
            app.allowSaveChanges();
            value = app.ChooseAnalysisModeDropDown.Value;
            switch value
                case 'Static'
                    set(app.AssistanceButton, 'Enable', 'off');
                    set(app.AssistanceButton, 'Value', 'Off');
                    setAssistanceOptions(app, 'off');
                    set(app.SegmentationSwitch, 'Value', 'Off')
                    set(app.SegmentationSwitch, 'Enable', 'off')
                    setSegmentationOptions(app, 'off')
                    set(app.MotionCompensationSwitch, 'Enable', 'off');
                    set(app.MotionCompensationSwitch, 'Value', 'Off')
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                    setCompensationOptions(app, 'off');
                    set(app.SpeedDataFolderButton, 'Enable', 'off');
                case 'Marker'
                    set(app.AssistanceButton, 'Enable', 'off');
                    set(app.AssistanceButton, 'Value', 'Off');
                    setAssistanceOptions(app, 'off');
                    set(app.StanceButton, 'Enable', 'off');
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    set(app.MotionCompensationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'off');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'off');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                    setCompensationOptions(app, app.MotionCompensationSwitch.Value);
                case 'GRF'
                    set(app.AssistanceButton, 'Enable', 'on');
                    set(app.StanceButton, 'Enable', 'on');
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    set(app.MotionCompensationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'off');
                    setSynchronisationOptions(app, 'off');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                    setCompensationOptions(app, app.MotionCompensationSwitch.Value);
                case 'Motion'
                    set(app.AssistanceButton, 'Enable', 'on');
                    set(app.SegmentationSwitch, 'Enable', 'on');
                    set(app.MotionCompensationSwitch, 'Enable', 'on');
                    setGRFOptions(app, 'on');
                    setMarkerOptions(app, 'on');
                    setSynchronisationOptions(app, 'on');
                    setSegmentationOptions(app, app.SegmentationSwitch.Value);
                    setCompensationOptions(app, app.MotionCompensationSwitch.Value);
            end
        end

        % Value changed function: SegmentationSwitch
        function allowSegmentation(app, event)
            setSegmentationOptions(app, app.SegmentationSwitch.Value)
        end

        % Button pushed function: GRFDataFolderButton
        function getGRFData(app, event)
            base = getBestStartPath(app);
            try
                app.GRFFolder.Value = uigetdir(base, 'Select folder containing GRF data');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: SaveDirectoryButton
        function getOrCreateResultsDir(app, event)
            base = getBestStartPath(app);
            try
                app.SaveDirectory.Value = uigetdir(base, 'Select or create results folder');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: ProcessButton
        function processData(app, event)
            if isempty(app.SaveDirectory.Value)
                app.TextArea.Value = 'Processing requires save directory.';
                return
            end
            
            % Default folder names.
            if app.DefaultSaveFoldersCheckBox.Value
                settings.static_folder = 'Static';
                settings.marker_folder = 'Markers';
                settings.grf_folder = 'GRF';
            else
                settings.static_folder = app.MarkersEditField.Value;
                settings.marker_folder = app.MarkersEditField.Value;
                settings.grf_folder = app.GRFEditField.Value;
            end
            
            settings.analysis = app.ChooseAnalysisModeDropDown.Value;
            settings.save_dir = app.SaveDirectory.Value;
            settings.info = app.DisplayDetailedInfoCheckBox.Value;
            switch app.ChooseAnalysisModeDropDown.Value
                case 'Static'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Static processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_system.forward = app.Markers_F.Value;
                        settings.marker_system.up = app.Markers_U.Value;
                        settings.marker_system.right = app.Markers_R.Value;
                    end
                case 'Marker'
                    if isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Marker processing requires marker data folder.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_system.forward = app.Markers_F.Value;
                        settings.marker_system.up = app.Markers_U.Value;
                        settings.marker_system.right = app.Markers_R.Value;
                    end
                case 'GRF'
                    if isempty(app.GRFFolder.Value)
                        app.TextArea.Value = 'GRF processing requires GRF data folder.';
                        return
                    else
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_system.forward = app.GRF_F.Value;
                        settings.grf_system.up = app.GRF_U.Value;
                        settings.grf_system.right = app.GRF_R.Value;
                    end
                case 'Motion'
                    if isempty(app.GRFFolder.Value) || isempty(app.MarkerFolder.Value)
                        app.TextArea.Value = 'Motion processing requires marker + GRF data folders.';
                        return
                    else
                        settings.markers = app.MarkerFolder.Value;
                        settings.marker_system.forward = app.Markers_F.Value;
                        settings.marker_system.up = app.Markers_U.Value;
                        settings.marker_system.right = app.Markers_R.Value;
                        settings.grfs = app.GRFFolder.Value;
                        settings.grf_system.forward = app.GRF_F.Value;
                        settings.grf_system.up = app.GRF_U.Value;
                        settings.grf_system.right = app.GRF_R.Value;
                        settings.x_offset = app.XOffset.Value;
                        settings.y_offset = app.YOffset.Value;
                        settings.z_offset = app.ZOffset.Value;
                        settings.time_delay = app.DataTimeDelay.Value;
                    end
            end
            app.TextArea.Value = 'Processing...';
            drawnow;
            if strcmp(app.SegmentationSwitch.Value, 'On') && ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Static')
                if app.StanceButton.Value
                    settings.mode = 'stance';
                else
                    settings.mode = 'toe-peak';
                end
                settings.cutoff = app.StanceCutoff.Value;
                if app.LeftButton.Value
                    settings.feet = {'left'};
                elseif app.RightButton.Value
                    settings.feet = {'right'};
                else
                    settings.feet = {'left', 'right'};
                end
            end
            
            if strcmp(app.MotionCompensationSwitch.Value, 'On') 
                if ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Static')
                    if isempty(app.SpeedFolder.Value)
                        settings.speed = app.MotionSpeed.Value;
                    else
                        settings.speed = app.SpeedFolder.Value;
                    end
                    if ~strcmp(app.ChooseAnalysisModeDropDown.Value, 'Marker')
                        settings.inclination = app.MotionTilt.Value;    
                    end
                end
            else
                settings.speed = 0;
                settings.inclination = 0;
            end
            
            if strcmp(app.AssistanceButton.Value, 'On')
                if strcmp(app.ChooseAnalysisModeDropDown.Value, 'GRF') || strcmp(app.ChooseAnalysisModeDropDown.Value, 'Motion')
                    settings.assistance_params = constructAssistanceParams(...
                        app.MagnitudeEditField.Value, app.RiseEditField.Value, ...
                        app.PeakEditField.Value, app.FallEditField.Value);
                end
            else
                settings.assistance_params = [];
            end
            
            % Control file subset behaviour.
            if ~isempty(app.FileSubset)
                settings.subset = app.FileSubset;
            end
            
            err = batchProcessData(settings);
            if ~err
                app.TextArea.Value = 'Processing complete.';
            else
                app.TextArea.Value = 'Processing finished with errors - see MATLAB command window for more details.';
            end
        end

        % Value changed function: DefaultSaveFoldersCheckBox
        function allowSaveChanges(app, event)
            if app.DefaultSaveFoldersCheckBox.Value
                status = 'off';
            else
                status = 'on';
            end
            
            switch app.ChooseAnalysisModeDropDown.Value
                case {'Static', 'Marker'}
                    set(app.MarkersEditField, 'Enable', status);
                    set(app.GRFEditField, 'Enable', 'off');
                case 'GRF'
                    set(app.MarkersEditField, 'Enable', 'off');
                    set(app.GRFEditField, 'Enable', status);
                case 'Motion'
                    set(app.GRFEditField, 'Enable', status);
                    set(app.MarkersEditField, 'Enable', status);
            end
        end

        % Value changed function: MotionCompensationSwitch
        function allowCompensation(app, event)
            setCompensationOptions(app, app.MotionCompensationSwitch.Value)
        end

        % Button pushed function: SpeedDataFolderButton
        function getSpeedData(app, event)
            base = getBestStartPath(app);
            try
                app.SpeedFolder.Value = uigetdir(base, 'Select folder containing GRF data');
            catch
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end

        % Button pushed function: FileSelectorButton
        function chooseFileSubset(app, event)
            app.FileSelectorButton.Enable = 'off';
            value = app.ChooseAnalysisModeDropDown.Value;
            switch value
                case {'Static', 'Marker', 'Motion'}
                    folder = app.MarkerFolder.Value;
                    ext = '.trc';
                otherwise
                    folder = app.GRFFolder.Value;
                    ext = '.txt';
            end
            if isempty(folder)
                app.TextArea.Value = 'Please input a source data folder.';
                app.FileSelectorButton.Enable = 'on';
                return
            else
                [~, files] = getFilePaths(folder, ext);
            end
            app.FileSet = files;
            app.FileSelector = FileSelectorApp(app, files);
        end

        % Close request function: MotionDataProcessingAppUIFigure
        function MotionDataProcessingAppUIFigureCloseRequest(app, event)
            
            delete(app.FileSelector);
            
            delete(app);
            
        end

        % Value changed function: OffsetOverrideSwitch
        function enableManualOffsets(app, event)
            value = app.OffsetOverrideSwitch.Value;
            
            set(app.XOffset, 'Enable', value)
            set(app.YOffset, 'Enable', value)
            set(app.ZOffset, 'Enable', value)
        end

        % Button pushed function: LoadCalibrationDataButton
        function getCalibrationFolder(app, event)
            base = getBestStartPath(app);
            try
                calibration_folder = uigetdir(base, 'Select folder containing calibration data');
            catch 
            end
            app.MotionDataProcessingAppUIFigure.Visible = 'off';
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
            app.TextArea.Value = ['Computing coordinate system offsets. Note: this step assumes '...
                'that all other processing information has already been input correctly.'];
            marker_system.forward = app.Markers_F.Value;
            marker_system.up = app.Markers_U.Value;
            marker_system.right = app.Markers_R.Value;
            grf_system.forward = app.GRF_F.Value;
            grf_system.up = app.GRF_U.Value;
            grf_system.right = app.GRF_R.Value;
            [X, Y, Z] = getCoordinateSystemOffsets(calibration_folder, marker_system, grf_system);
            app.XOffset.Value = X;
            app.YOffset.Value = Y;
            app.ZOffset.Value = Z;
        end

        % Value changed function: AssistanceButton
        function allowAssistance(app, event)
            value = app.AssistanceButton.Value;
            set(app.MagnitudeEditField, 'Enable', value);
            set(app.RiseEditField, 'Enable', value);
            set(app.PeakEditField, 'Enable', value);
            set(app.FallEditField, 'Enable', value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MotionDataProcessingAppUIFigure = uifigure('Visible', 'off');
            app.MotionDataProcessingAppUIFigure.Name = 'Motion Data Processing App';
            app.MotionDataProcessingAppUIFigure.CloseRequestFcn = createCallbackFcn(app, @MotionDataProcessingAppUIFigureCloseRequest, true);
            app.ChooseAnalysisModeDropDownLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDownLabel.Text = 'Choose Analysis Mode';
            app.ChooseAnalysisModeDropDown = uidropdown(app.MotionDataProcessingAppUIFigure);
            app.ChooseAnalysisModeDropDown.Items = {'Static', 'Marker', 'GRF', 'Motion'};
            app.ChooseAnalysisModeDropDown.ValueChangedFcn = createCallbackFcn(app, @disableOptions, true);
            app.ChooseAnalysisModeDropDown.Value = 'Static';
            app.MarkerDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.MarkerDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getMarkerFolder, true);
            app.MarkerDataFolderButton.Text = 'Marker Data Folder';
            app.MarkerFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkerFolder.Editable = 'off';
            app.GRFDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.GRFDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getGRFData, true);
            app.GRFDataFolderButton.Text = 'GRF Data Folder';
            app.GRFFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFFolder.Editable = 'off';
            app.SaveDirectoryButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.SaveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @getOrCreateResultsDir, true);
            app.SaveDirectoryButton.Text = 'Save Directory';
            app.SaveDirectory = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.SaveDirectory.Editable = 'off';
            app.DisplayDetailedInfoCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DisplayDetailedInfoCheckBox.Text = ' Display Detailed Info';
            app.ProcessButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @processData, true);
            app.ProcessButton.Text = 'Process';
            app.SegmentationSwitchLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.SegmentationSwitchLabel.Text = 'Segmentation';
            app.SegmentationSwitch = uiswitch(app.MotionDataProcessingAppUIFigure, 'slider');
            app.SegmentationSwitch.ValueChangedFcn = createCallbackFcn(app, @allowSegmentation, true);
            app.TextArea = uitextarea(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ComponentofGaitAnalysisToolboxLabel.Text = 'Component of Gait Analysis Toolbox  ';
            app.DanielGordon2019Label = uilabel(app.MotionDataProcessingAppUIFigure);
            app.DanielGordon2019Label.Text = 'Daniel Gordon 2019';
            app.DefaultSaveFoldersCheckBox = uicheckbox(app.MotionDataProcessingAppUIFigure);
            app.DefaultSaveFoldersCheckBox.ValueChangedFcn = createCallbackFcn(app, @allowSaveChanges, true);
            app.DefaultSaveFoldersCheckBox.Text = 'Default Save Folders';
            app.DefaultSaveFoldersCheckBox.Value = true;
            app.MotionCompensationSwitchLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.MotionCompensationSwitchLabel.Text = 'Motion Compensation';
            app.MotionCompensationSwitch = uiswitch(app.MotionDataProcessingAppUIFigure, 'slider');
            app.MotionCompensationSwitch.ValueChangedFcn = createCallbackFcn(app, @allowCompensation, true);
            app.SpeedDataFolderButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.SpeedDataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @getSpeedData, true);
            app.SpeedDataFolderButton.Enable = 'off';
            app.SpeedDataFolderButton.Text = 'Speed Data Folder';
            app.SpeedFolder = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.CoordinateTransformationPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.CoordinateTransformationPanel.Title = 'Coordinate Transformation';
            app.ForwardLabel = uilabel(app.CoordinateTransformationPanel);
            app.ForwardLabel.Text = 'Forward';
            app.UpLabel = uilabel(app.CoordinateTransformationPanel);
            app.UpLabel.Text = 'Up';
            app.RightLabel = uilabel(app.CoordinateTransformationPanel);
            app.RightLabel.Text = 'Right';
            app.MarkersLabel = uilabel(app.CoordinateTransformationPanel);
            app.MarkersLabel.Text = 'Markers';
            app.GRFLabel = uilabel(app.CoordinateTransformationPanel);
            app.GRFLabel.Text = 'GRF';
            app.Markers_F = uidropdown(app.CoordinateTransformationPanel);
            app.Markers_F.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.Markers_F.Value = '+z';
            app.Markers_U = uidropdown(app.CoordinateTransformationPanel);
            app.Markers_U.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.Markers_U.Value = '+y';
            app.Markers_R = uidropdown(app.CoordinateTransformationPanel);
            app.Markers_R.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.Markers_R.Value = '-x';
            app.GRF_F = uidropdown(app.CoordinateTransformationPanel);
            app.GRF_F.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRF_F.Value = '+y';
            app.GRF_U = uidropdown(app.CoordinateTransformationPanel);
            app.GRF_U.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRF_U.Value = '-z';
            app.GRF_R = uidropdown(app.CoordinateTransformationPanel);
            app.GRF_R.Items = {'+x', '-x', '+y', '-y', '+z', '-z'};
            app.GRF_R.Value = '+x';
            app.GaitSegmentationPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.GaitSegmentationPanel.Title = 'Gait Segmentation';
            app.ModeButtonGroup = uibuttongroup(app.GaitSegmentationPanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.StanceButton = uiradiobutton(app.ModeButtonGroup);
            app.StanceButton.Text = 'Stance';
            app.StanceButton.Value = true;
            app.HipAngleButton = uiradiobutton(app.ModeButtonGroup);
            app.HipAngleButton.Text = 'Hip Angle';
            app.FeetButtonGroup = uibuttongroup(app.GaitSegmentationPanel);
            app.FeetButtonGroup.Title = 'Feet';
            app.LeftButton = uiradiobutton(app.FeetButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.FeetButtonGroup);
            app.RightButton.Text = 'Right';
            app.BothButton = uiradiobutton(app.FeetButtonGroup);
            app.BothButton.Text = 'Both';
            app.CutoffNLabel = uilabel(app.GaitSegmentationPanel);
            app.CutoffNLabel.Text = 'Cutoff (N)';
            app.StanceCutoff = uieditfield(app.GaitSegmentationPanel, 'numeric');
            app.StanceCutoff.Value = 2;
            app.DataTimeDelaysLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.DataTimeDelaysLabel.Text = 'Data Time Delay (s)';
            app.DataTimeDelay = uieditfield(app.MotionDataProcessingAppUIFigure, 'numeric');
            app.MotionCompensationPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.MotionCompensationPanel.Title = 'Motion Compensation';
            app.MotionSpeedmsLabel = uilabel(app.MotionCompensationPanel);
            app.MotionSpeedmsLabel.Text = 'Motion Speed (m/s)';
            app.MotionSpeed = uieditfield(app.MotionCompensationPanel, 'numeric');
            app.MotionTiltdegLabel = uilabel(app.MotionCompensationPanel);
            app.MotionTiltdegLabel.Text = 'Motion Tilt (deg)';
            app.MotionTilt = uieditfield(app.MotionCompensationPanel, 'numeric');
            app.MarkersEditField = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.MarkersEditField.Enable = 'off';
            app.MarkersEditField.Value = 'Markers';
            app.MarkersEditFieldLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.MarkersEditFieldLabel.Text = 'Markers';
            app.GRFEditField = uieditfield(app.MotionDataProcessingAppUIFigure, 'text');
            app.GRFEditField.Enable = 'off';
            app.GRFEditField.Value = 'Forces';
            app.GRFEditFieldLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.GRFEditFieldLabel.Text = 'GRF';
            app.FileSelectorButton = uibutton(app.MotionDataProcessingAppUIFigure, 'push');
            app.FileSelectorButton.ButtonPushedFcn = createCallbackFcn(app, @chooseFileSubset, true);
            app.FileSelectorButton.Text = 'Choose Subset of Data Files';
            app.SubsetSelectedLampLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.SubsetSelectedLampLabel.Text = 'Subset Selected?';
            app.SubsetSelectedLamp = uilamp(app.MotionDataProcessingAppUIFigure);
            app.CoordinateSystemOffsetsPanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.CoordinateSystemOffsetsPanel.Title = 'Coordinate System Offsets';
            app.YEditFieldLabel = uilabel(app.CoordinateSystemOffsetsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YOffset = uieditfield(app.CoordinateSystemOffsetsPanel, 'numeric');
            app.YOffset.ValueDisplayFormat = '%.2f';
            app.YOffset.Enable = 'off';
            app.ZEditFieldLabel = uilabel(app.CoordinateSystemOffsetsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZOffset = uieditfield(app.CoordinateSystemOffsetsPanel, 'numeric');
            app.ZOffset.ValueDisplayFormat = '%.2f';
            app.ZOffset.Enable = 'off';
            app.XEditFieldLabel = uilabel(app.CoordinateSystemOffsetsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XOffset = uieditfield(app.CoordinateSystemOffsetsPanel, 'numeric');
            app.XOffset.ValueDisplayFormat = '%.2f';
            app.XOffset.Enable = 'off';
            app.LoadCalibrationDataButton = uibutton(app.CoordinateSystemOffsetsPanel, 'push');
            app.LoadCalibrationDataButton.ButtonPushedFcn = createCallbackFcn(app, @getCalibrationFolder, true);
            app.LoadCalibrationDataButton.Text = 'Load Calibration Data';
            app.ManualOverrideSwitchLabel = uilabel(app.MotionDataProcessingAppUIFigure);
            app.ManualOverrideSwitchLabel.Text = 'Manual Override';
            app.OffsetOverrideSwitch = uiswitch(app.MotionDataProcessingAppUIFigure, 'slider');
            app.OffsetOverrideSwitch.ValueChangedFcn = createCallbackFcn(app, @enableManualOffsets, true);
            app.ApplyParameterisedAssistancePanel = uipanel(app.MotionDataProcessingAppUIFigure);
            app.ApplyParameterisedAssistancePanel.Title = 'Apply Parameterised Assistance';
            app.FallEditFieldLabel = uilabel(app.ApplyParameterisedAssistancePanel);
            app.FallEditFieldLabel.Text = 'Fall';
            app.FallEditField = uieditfield(app.ApplyParameterisedAssistancePanel, 'numeric');
            app.FallEditField.Enable = 'off';
            app.PeakLabel = uilabel(app.ApplyParameterisedAssistancePanel);
            app.PeakLabel.Text = 'Peak';
            app.PeakEditField = uieditfield(app.ApplyParameterisedAssistancePanel, 'numeric');
            app.PeakEditField.Enable = 'off';
            app.RiseLabel = uilabel(app.ApplyParameterisedAssistancePanel);
            app.RiseLabel.Text = 'Rise';
            app.RiseEditField = uieditfield(app.ApplyParameterisedAssistancePanel, 'numeric');
            app.RiseEditField.Enable = 'off';
            app.MagnitudeLabel = uilabel(app.ApplyParameterisedAssistancePanel);
            app.MagnitudeLabel.Text = 'Magnitude';
            app.MagnitudeEditField = uieditfield(app.ApplyParameterisedAssistancePanel, 'numeric');
            app.MagnitudeEditField.Enable = 'off';
            app.AssistanceButton = uiswitch(app.ApplyParameterisedAssistancePanel, 'slider');
            app.AssistanceButton.ValueChangedFcn = createCallbackFcn(app, @allowAssistance, true);
            app.AssistanceButton.Enable = 'off';
            % Show the figure after all components are created
            app.MotionDataProcessingAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ProcessMotionData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MotionDataProcessingAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MotionDataProcessingAppUIFigure)
        end
    end
end